classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        RobotPanel    matlab.ui.container.Panel
        TabGroup      matlab.ui.container.TabGroup
        Tab           matlab.ui.container.Tab
        UIAxes_C1     matlab.ui.control.UIAxes
        Tab2          matlab.ui.container.Tab
        UIAxes_C2     matlab.ui.control.UIAxes
        Tab3          matlab.ui.container.Tab
        UIAxes_C3     matlab.ui.control.UIAxes
        Tab4          matlab.ui.container.Tab
        UIAxes_C4     matlab.ui.control.UIAxes
        Tab5          matlab.ui.container.Tab
        UIAxes_C5     matlab.ui.control.UIAxes
        Tab6          matlab.ui.container.Tab
        UIAxes_C6     matlab.ui.control.UIAxes
        ReloadButton  matlab.ui.control.Button
        StopButton    matlab.ui.control.Button
        StartButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        CurrentStep = 1;
        IsSimulating = false;
        TrajectoryData;
        RobotModel;
        RobotAxes;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                appPath = fileparts(which(mfilename));
                modelPath = fullfile(appPath, 'model_geometric.slx');
                app.RobotModel = importrobot(modelPath); 
                app.RobotModel.DataFormat = 'row';
                app.RobotAxes = axes(app.RobotPanel); 
                view(app.RobotAxes, [-37.5 30]);
                grid(app.RobotAxes, 'on');
                axis(app.RobotAxes, 'tight');
            catch
                uialert(app.UIFigure, 'The Simulink Model was not found', 'Error');
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.IsSimulating = true;
            app.StartButton.Enable = 'off';
            if isempty(app.TrajectoryData) || app.CurrentStep == 1
                try
                    app.TrajectoryData.t = evalin('base', 't');
                    numSteps = length(app.TrajectoryData.t);
                    app.TrajectoryData.pos = zeros(numSteps, 6);
                    app.TrajectoryData.vel = zeros(numSteps, 6);
                    app.TrajectoryData.acc = zeros(numSteps, 6);
                    for i = 1:6
                        p_ts = evalin('base', ['p', num2str(i)]); 
                        if isa(p_ts, 'timeseries')
                            p_data = p_ts.Data;
                        else
                            p_data = p_ts;
                        end
                        p_data = p_data(:); 
                        if length(p_data) > numSteps
                            p_data = p_data(1:numSteps);
                        elseif length(p_data) < numSteps
                            error('Datele p%d sunt mai scurte decât vectorul de timp t!', i);
                        end
                        
                        app.TrajectoryData.pos(:, i) = p_data;
                        app.TrajectoryData.vel(:, i) = gradient(p_data, app.TrajectoryData.t);
                        app.TrajectoryData.acc(:, i) = gradient(app.TrajectoryData.vel(:, i), app.TrajectoryData.t);
                    end
                catch
                    uialert(app.UIFigure, 'Assure that t, p1, p2, ..., p6 exist in Workspace!', 'Error');
                    app.IsSimulating = false;
                    app.StartButton.Enable = 'on';
                    return;
                end
            end
            for k = app.CurrentStep : 10 : length(app.TrajectoryData.t)
                if ~app.IsSimulating
                    app.CurrentStep = k; 
                    app.StartButton.Enable = 'on';
                    return;
                end
        
                    title(app.RobotAxes, sprintf('Time: %.2f s', app.TrajectoryData.t(k)));                
                    if ~isempty(app.RobotModel)
                        show(app.RobotModel, app.TrajectoryData.pos(k, :), 'Parent', app.RobotAxes, 'PreservePlot', false);
                        axis(app.RobotAxes, 'tight');  
                        axis(app.RobotAxes, 'equal'); 
                    end
                for i = 1:6
                    ax_target = app.(['UIAxes_C', num2str(i)]);
                    cla(ax_target);
                    hold(ax_target, 'on');
                    plot(ax_target, app.TrajectoryData.t(1:k), app.TrajectoryData.pos(1:k, i), 'Color', 'b', 'LineWidth', 1.5, 'DisplayName', 'Position');
                    plot(ax_target, app.TrajectoryData.t(1:k), app.TrajectoryData.vel(1:k, i), 'Color', 'r', 'LineStyle', '--', 'LineWidth', 1.2, 'DisplayName', 'Velocity');
                    plot(ax_target, app.TrajectoryData.t(1:k), app.TrajectoryData.acc(1:k, i), 'Color', [0 0.5 0], 'LineStyle', ':', 'LineWidth', 1.2, 'DisplayName', 'Acceleration');
                    grid(ax_target, 'on');
                    if k == app.CurrentStep
                        legend(ax_target, 'show', 'Location', 'northeast');
                    end
                    xlabel(ax_target, 'Time [s]');
                    ylabel(ax_target, 'Kinematic Values');
                    hold(ax_target, 'off');
                end
                drawnow limitrate; 
            end
            app.IsSimulating = false;
            app.CurrentStep = 1;
            app.StartButton.Enable = 'on';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.IsSimulating = false;
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            app.CurrentStep = 1;
            if ~isempty(app.RobotAxes)
                cla(app.RobotAxes);
            end
            for i = 1:6
                cla(app.(['UIAxes_C', num2str(i)]));
            end
            StartButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ReloadButton = uibutton(app.UIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes_C1 = uiaxes(app.Tab);
            title(app.UIAxes_C1, 'Title')
            xlabel(app.UIAxes_C1, 'X')
            ylabel(app.UIAxes_C1, 'Y')
            zlabel(app.UIAxes_C1, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes_C2 = uiaxes(app.Tab2);
            title(app.UIAxes_C2, 'Title')
            xlabel(app.UIAxes_C2, 'X')
            ylabel(app.UIAxes_C2, 'Y')
            zlabel(app.UIAxes_C2, 'Z')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes_C3 = uiaxes(app.Tab3);
            title(app.UIAxes_C3, 'Title')
            xlabel(app.UIAxes_C3, 'X')
            ylabel(app.UIAxes_C3, 'Y')
            zlabel(app.UIAxes_C3, 'Z')
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.UIAxes_C4 = uiaxes(app.Tab4);
            title(app.UIAxes_C4, 'Title')
            xlabel(app.UIAxes_C4, 'X')
            ylabel(app.UIAxes_C4, 'Y')
            zlabel(app.UIAxes_C4, 'Z')
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.UIAxes_C5 = uiaxes(app.Tab5);
            title(app.UIAxes_C5, 'Title')
            xlabel(app.UIAxes_C5, 'X')
            ylabel(app.UIAxes_C5, 'Y')
            zlabel(app.UIAxes_C5, 'Z')
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.UIAxes_C6 = uiaxes(app.Tab6);
            title(app.UIAxes_C6, 'Title')
            xlabel(app.UIAxes_C6, 'X')
            ylabel(app.UIAxes_C6, 'Y')
            zlabel(app.UIAxes_C6, 'Z')
            app.RobotPanel = uipanel(app.UIFigure);
            app.RobotPanel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end